classdef Sound_signal_denoising_using_wavelet_transform < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        WhiteGaussiannoiseTab        matlab.ui.container.Tab
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UniformnoiseTab              matlab.ui.container.Tab
        UIAxes3_2                    matlab.ui.control.UIAxes
        UIAxes2_2                    matlab.ui.control.UIAxes
        ImpulsivenoiseTab            matlab.ui.container.Tab
        UIAxes3_3                    matlab.ui.control.UIAxes
        UIAxes2_3                    matlab.ui.control.UIAxes
        Panel                        matlab.ui.container.Panel
        ImpulsivenoisePanel          matlab.ui.container.Panel
        Button_6                     matlab.ui.control.Button
        DenoiseButton_3              matlab.ui.control.Button
        Button_7                     matlab.ui.control.Button
        AddImpulsivenoiseButton      matlab.ui.control.Button
        UniformnoisePanel            matlab.ui.container.Panel
        Button_5                     matlab.ui.control.Button
        Button_4                     matlab.ui.control.Button
        AddUniformnoiseButton        matlab.ui.control.Button
        DenoiseButton_2              matlab.ui.control.Button
        WhiteGaussiannoisePanel      matlab.ui.container.Panel
        Button_3                     matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        DenoiseButton                matlab.ui.control.Button
        AddWhiteGaussianNoiseButton  matlab.ui.control.Button
        OriginalVoicePanel           matlab.ui.container.Panel
        Button                       matlab.ui.control.Button
        LoadVoiceButton              matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x                double  % Define x as a property % Description
        Fs               double  % Define Fs as a property % Description
        gn               double  % Define gn as a property % white Gaussian noise
        dgn              double  % Define dgn as a property % white Gaussian Denoise
        un               double  % Define un as a property % Uniform noise
        dun              double  % Define dun as a property % Uniform Denoise
        imn              double  % Define imn as a property % Impulsive noise
        dimn             double  % Define dimn as a property % Impulsive Denoise
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadVoiceButton
        function LoadVoiceButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.wav';'*.mp3';'*.ogg';'*.flac'}, 'Select an audio file');
        if isequal(filename,0)
            return; % User pressed cancel
        end
        [app.x, app.Fs] = audioread(fullfile(pathname, filename));
        t = (0:length(app.x)-1)/app.Fs; % Create time vector
        plot(app.UIAxes, t, app.x);    % Plot the sound wave
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            sound(app.x, app.Fs);
        end

        % Button pushed function: AddWhiteGaussianNoiseButton
        function AddWhiteGaussianNoiseButtonPushed(app, event)
        % Adding white Gaussian noise
        SNR = 10;                                           % Signal-to-Noise Ratio in dB
        app.gn = awgn(app.x, SNR, 'measured');
        filename = 'white Gaussian noisy voice.wav';   % Saving the file as noisyvoice.wav
        audiowrite(filename, app.gn, app.Fs);
        t = (0:length(app.gn)-1)/app.Fs; % Create time vector
        plot(app.UIAxes2, t, app.gn);    % Plot the sound wave      
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            sound(app.gn, app.Fs); 
        end

        % Button pushed function: DenoiseButton
        function DenoiseButtonPushed(app, event)
            % Denoising using wavelet transform
            wname = 'coif5';
            lev = 10;
            tree = wpdec(app.gn, lev, wname);
            det1 = wpcoef(tree, 2014);
            sigma = median(abs(det1)) / 0.6745;
            alpha = 1.8;
            thr = wpbmpen(tree, sigma, alpha);
            keepapp = 1;

            % Denoising
            app.dgn = wpdencmp(tree, 's', 'nobest', thr, keepapp);
            t = (0:length(app.dgn)-1)/app.Fs; % Create time vector
            plot(app.UIAxes3, t, app.dgn);    % Plot the sound wave
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            sound(app.dgn, app.Fs);
        end

        % Button pushed function: AddUniformnoiseButton
        function AddUniformnoiseButtonPushed(app, event)
            % Adding Uniform noise
            amplitude = 0.5;                                        % Adjust the amplitude to control the noise level
            app.un = app.x + amplitude * (rand(size(app.x)) - 0.5); % Adding uniform noise
            filename = 'Uniform noisy voice.wav';                   % Saving the file as noisyvoice.wav
            audiowrite(filename, app.un, app.Fs);
            t = (0:length(app.un)-1)/app.Fs; % Create time vector
            plot(app.UIAxes2_2, t, app.un);    % Plot the sound wave
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            sound(app.un, app.Fs);
        end

        % Button pushed function: DenoiseButton_2
        function DenoiseButton_2Pushed(app, event)
            % Median Filtering
            y_median = medfilt1(app.un, 3);             % Apply a 3-point median filter
            % Gaussian Filtering
            sigma = 1;                                  % Adjust the standard deviation as needed
            h = fspecial('gaussian', [5, 1], sigma);
            app.dun = conv(y_median, h, 'same');
            t = (0:length(app.dun)-1)/app.Fs; % Create time vector
            plot(app.UIAxes3_2, t, app.dun);    % Plot the sound wave
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            sound(app.dun, app.Fs);
        end

        % Button pushed function: AddImpulsivenoiseButton
        function AddImpulsivenoiseButtonPushed(app, event)
            % Adding 'Impulsive' noise
            p = 0.05;                                           % Probability of occurrence
            app.imn = app.x;
            salt_pepper = rand(size(app.x));
            app.imn(salt_pepper < p/2) = 1;                     % Set to high value
            app.imn(salt_pepper >= p/2 & salt_pepper < p) = -1; % Set to low value
            filename = 'Impulsive noisy voice.wav';                        % Saving the file as noisyvoice.wav
            audiowrite(filename, app.imn, app.Fs);
            t = (0:length(app.imn)-1)/app.Fs;   % Create time vector
            plot(app.UIAxes2_3, t, app.imn);    % Plot the sound wave
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            sound(app.imn, app.Fs);
        end

        % Button pushed function: DenoiseButton_3
        function DenoiseButton_3Pushed(app, event)
            % Median Filtering Minimum: 3 (Smallest possible window size)
            y_median = medfilt1(app.imn, 3);                  % Apply a 3-point median filter
            sigma = 1;                                        % Adjust the standard deviation as needed
            h = fspecial('gaussian', [5, 1], sigma);
            app.dimn = conv(y_median, h, 'same');
            t = (0:length(app.dimn)-1)/app.Fs;   % Create time vector
            plot(app.UIAxes3_3, t, app.dimn);    % Plot the sound wave
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            sound(app.dimn, app.Fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Origenal Wave')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.OriginalVoicePanel = uipanel(app.Panel);
            app.OriginalVoicePanel.Title = 'Original Voice';
            app.LoadVoiceButton = uibutton(app.OriginalVoicePanel, 'push');
            app.LoadVoiceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVoiceButtonPushed, true);
            app.LoadVoiceButton.Text = 'Load Voice';
            app.Button = uibutton(app.OriginalVoicePanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '▶';
            app.WhiteGaussiannoisePanel = uipanel(app.Panel);
            app.WhiteGaussiannoisePanel.Title = 'White Gaussian noise';
            app.AddWhiteGaussianNoiseButton = uibutton(app.WhiteGaussiannoisePanel, 'push');
            app.AddWhiteGaussianNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddWhiteGaussianNoiseButtonPushed, true);
            app.AddWhiteGaussianNoiseButton.Text = 'Add White Gaussian Noise';
            app.DenoiseButton = uibutton(app.WhiteGaussiannoisePanel, 'push');
            app.DenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @DenoiseButtonPushed, true);
            app.DenoiseButton.Text = 'Denoise';
            app.Button_2 = uibutton(app.WhiteGaussiannoisePanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '▶';
            app.Button_3 = uibutton(app.WhiteGaussiannoisePanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '▶';
            app.UniformnoisePanel = uipanel(app.Panel);
            app.UniformnoisePanel.Title = 'Uniform noise';
            app.DenoiseButton_2 = uibutton(app.UniformnoisePanel, 'push');
            app.DenoiseButton_2.ButtonPushedFcn = createCallbackFcn(app, @DenoiseButton_2Pushed, true);
            app.DenoiseButton_2.Text = 'Denoise';
            app.AddUniformnoiseButton = uibutton(app.UniformnoisePanel, 'push');
            app.AddUniformnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddUniformnoiseButtonPushed, true);
            app.AddUniformnoiseButton.Text = 'Add Uniform noise';
            app.Button_4 = uibutton(app.UniformnoisePanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '▶';
            app.Button_5 = uibutton(app.UniformnoisePanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '▶';
            app.ImpulsivenoisePanel = uipanel(app.Panel);
            app.ImpulsivenoisePanel.Title = 'Impulsive noise';
            app.AddImpulsivenoiseButton = uibutton(app.ImpulsivenoisePanel, 'push');
            app.AddImpulsivenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddImpulsivenoiseButtonPushed, true);
            app.AddImpulsivenoiseButton.Text = 'Add Impulsive noise';
            app.Button_7 = uibutton(app.ImpulsivenoisePanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '▶';
            app.DenoiseButton_3 = uibutton(app.ImpulsivenoisePanel, 'push');
            app.DenoiseButton_3.ButtonPushedFcn = createCallbackFcn(app, @DenoiseButton_3Pushed, true);
            app.DenoiseButton_3.Text = 'Denoise';
            app.Button_6 = uibutton(app.ImpulsivenoisePanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '▶';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.WhiteGaussiannoiseTab = uitab(app.TabGroup);
            app.WhiteGaussiannoiseTab.Title = '   White Gaussian noise   ';
            app.UIAxes2 = uiaxes(app.WhiteGaussiannoiseTab);
            title(app.UIAxes2, 'White Gaussian Noise Wave')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.WhiteGaussiannoiseTab);
            title(app.UIAxes3, 'White Gaussian Denoise Wave')
            xlabel(app.UIAxes3, 'Time (s)')
            ylabel(app.UIAxes3, 'Amplitude')
            zlabel(app.UIAxes3, 'Z')
            app.UniformnoiseTab = uitab(app.TabGroup);
            app.UniformnoiseTab.Title = '   Uniform noise   ';
            app.UIAxes2_2 = uiaxes(app.UniformnoiseTab);
            title(app.UIAxes2_2, 'Uniform Noise Wave')
            xlabel(app.UIAxes2_2, 'Time (s)')
            ylabel(app.UIAxes2_2, 'Amplitude')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes3_2 = uiaxes(app.UniformnoiseTab);
            title(app.UIAxes3_2, 'Uniform Denoise Wave')
            xlabel(app.UIAxes3_2, 'Time (s)')
            ylabel(app.UIAxes3_2, 'Amplitude')
            zlabel(app.UIAxes3_2, 'Z')
            app.ImpulsivenoiseTab = uitab(app.TabGroup);
            app.ImpulsivenoiseTab.Title = '   Impulsive noise   ';
            app.UIAxes2_3 = uiaxes(app.ImpulsivenoiseTab);
            title(app.UIAxes2_3, 'Impulsive Noise Wave')
            xlabel(app.UIAxes2_3, 'Time (s)')
            ylabel(app.UIAxes2_3, 'Amplitude')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes3_3 = uiaxes(app.ImpulsivenoiseTab);
            title(app.UIAxes3_3, 'Impulsive Denoise Wave')
            xlabel(app.UIAxes3_3, 'Time (s)')
            ylabel(app.UIAxes3_3, 'Amplitude')
            zlabel(app.UIAxes3_3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sound_signal_denoising_using_wavelet_transform

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end